classdef ViewFrecuency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViewFrecuencyUIFigure  matlab.ui.Figure
        Signal1Panel           matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % Description
        graph1 = []
        graph2 = []
        graph3 = []
        graph4 = []
        fs = []
        
    end
    
    methods (Access = public)
            
            function data = plot_fft(app, signal, fs)
                    % Realizar FFT normal
                    fft_result = fft(signal);
                    
                    % Obtener la mitad positiva de la FFT
                    fft_result = fft_result(1:length(fft_result)/2);
            
                    % Calcular la magnitud en decibeles
                    fft_result_dB = (abs(fft_result) / length(fft_result));
            
                    % Generar el vector de frecuencias para la mitad positiva
                    f = linspace(0, fs/2, length(fft_result));
                    data = [fft_result_dB; f];
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, option, input1, input2, input3, input4, fs)
            movegui(app.ViewFrecuencyUIFigure, 'center');
            app.CallingApp = caller;
            switch option
                case 1
                    if ~isempty(app.CallingApp.dataSignal1_1)
                        app.UIAxes.Title.String = "Channel 1";
                        signal = input1(2,:);
                        fs1 = fs(1);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes,data(2,:), data(1,:));
                    end

                    if ~isempty(app.CallingApp.dataSignal1_2)
                        app.UIAxes_2.Title.String = "Channel 2";
                        signal = input2(2,:);
                        fs1 = fs(2);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_2,data(2,:), data(1,:));
                    end

                      if ~isempty(app.CallingApp.dataSignal1_3)
                        app.UIAxes_3.Title.String = "Out 1";
                        signal = input3(2,:);
                        fs1 = fs(3);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_3,data(2,:), data(1,:));
                      end

                    if ~isempty(app.CallingApp.dataSignal1_4)
                        app.UIAxes_4.Title.String = "Out 2";
                        signal = input4(2,:);
                        fs1 = fs(4);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_4,data(2,:), data(1,:));
                    end

                case 2
                    app.Signal1Panel.Title = "Signal 2";
                    if ~isempty(app.CallingApp.dataSignal2_1)
                        app.UIAxes.Title.String = "Channel 1";
                        signal = input1(2,:);
                        fs1 = fs(1);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes,data(2,:), data(1,:));
                    end

                    if ~isempty(app.CallingApp.dataSignal2_2)
                        app.UIAxes_2.Title.String = "Channel 2";
                        signal = input2(2,:);
                        fs1 = fs(2);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_2,data(2,:), data(1,:));
                    end

                      if ~isempty(app.CallingApp.dataSignal2_3)
                        app.UIAxes_3.Title.String = "Out 1";
                        signal = input3(2,:);
                        fs1 = fs(3);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_3,data(2,:), data(1,:));
                      end

                    if ~isempty(app.CallingApp.dataSignal2_4)
                        app.UIAxes_4.Title.String = "Out 2";
                        signal = input4(2,:);
                        fs1 = fs(4);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_4,data(2,:), data(1,:));
                    end

                case 3
                    app.Signal1Panel.Title = "Results and Filters";
                    if ~isempty(app.CallingApp.dataSignalResult1)
                        app.UIAxes.Title.String = "Result 1";
                        signal = input1(2,:);
                        fs1 = fs(1);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes,data(2,:), data(1,:));
                    end

                    if ~isempty(app.CallingApp.dataSignalResult2)
                        app.UIAxes_2.Title.String = "Result 2";
                        signal = input2(2,:);
                        fs1 = fs(2);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_2,data(2,:), data(1,:));
                    end

                      if ~isempty(app.CallingApp.dataSignal1_3)
                        app.UIAxes_3.Title.String = "Out 1";
                        signal = input3(2,:);
                        fs1 = fs(3);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_3,data(2,:), data(1,:));
                      end

                    if ~isempty(app.CallingApp.dataSignal1_4)
                        app.UIAxes_4.Title.String = "Out 2";
                        signal = input4(2,:);
                        fs1 = fs(4);
                        data = plot_fft(app, signal, fs1);
                        plot(app.UIAxes_4,data(2,:), data(1,:));
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ViewFrecuencyUIFigure = uifigure('Visible', 'off');
            app.ViewFrecuencyUIFigure.Name = 'View Frecuency';
            app.Signal1Panel = uipanel(app.ViewFrecuencyUIFigure);
            app.Signal1Panel.Title = 'Signal 1';
            app.GridLayout = uigridlayout(app.Signal1Panel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Channel 1')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Channel 2')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Out 1')
            xlabel(app.UIAxes_3, 'Frequency (Hz)')
            ylabel(app.UIAxes_3, 'Magnitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Out 2')
            xlabel(app.UIAxes_4, 'Frequency (Hz)')
            ylabel(app.UIAxes_4, 'Magnitude')
            zlabel(app.UIAxes_4, 'Z')
            % Show the figure after all components are created
            app.ViewFrecuencyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewFrecuency(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViewFrecuencyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViewFrecuencyUIFigure)
        end
    end
end